classdef DiffractionDispersionSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SourceMenu                   matlab.ui.control.DropDown
        IncidentLightSourceLabel     matlab.ui.control.Label
        AngleBox                     matlab.ui.control.NumericEditField
        GlassMenu                    matlab.ui.control.DropDown
        OpticalGlassDropDownLabel    matlab.ui.control.Label
        AngleSlider                  matlab.ui.control.Slider
        StartingRayAngleSliderLabel  matlab.ui.control.Label
        ElementsMenu                 matlab.ui.control.DropDown
        OpticalElementsInterfacesDropDownLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        TitleText                    matlab.ui.control.Label
        Axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ElementsMenu
        function ElementsMenuValueChanged(app, event)
            value = app.ElementsMenu.Value;
            if strcmp(value,'Air/Glass Surface')
                medium2 = polyshape([0.5 0.5 1 1],[1 0 0 1]);
                plot(app.Axes, medium2,'FaceColor', 'cyan')
                title(app.Axes, 'Air/Glass Surface');

            elseif strcmp(value,'Glass/Air Surface')
                 medium1 = polyshape([0 0 0.5 0.5], [1 0 0 1]);
                 plot(app.Axes, medium1, 'FaceColor', 'yellow');
                 title(app.Axes, 'Glass/Air Surface');

            elseif  strcmp(value,'Plane Parallel Plate')
                    plate = polyshape([0.3 0.3 0.7 0.7], [0.8 0.2 0.2 0.8]);
                    plot(app.Axes, plate, 'FaceColor', 'green');
                    title(app.Axes, 'Plane Parallel Plate');

            elseif strcmp(value,'60-Degree Prism')
                    prism = polyshape([0.4 0.6 0.5], [0.2 0.2 0.5]);
                    plot(app.Axes, prism, 'FaceColor', 'blue');
                    title(app.Axes, '60-Degree Prism');

            elseif  strcmp(value,'Simple Positive Lens')
                    lens = polyshape([0.4 0.6 0.55], [0.3 0.3 0.7]);
                    plot(app.Axes, lens, 'FaceColor', 'magenta');
                    title(app.Axes, 'Simple Positive Lens');

            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SourceMenu
        function SourceMenuValueChanged(app, event)
        source = app.SourceMenu.Value;
        switch source
            case 'white light'
                    title(app.Axes, 'white light source');
            case 'red chromatic light'
                    title(app.Axes, 'red chromatic light source');
            case 'yellow chromatic light'
                    title(app.Axes, 'red chromatic light source');  
            case 'green chromatic light'
                    title(app.Axes, 'red chromatic light source');
            case 'blue chromatic light'
                    title(app.Axes, 'red chromatic light source');

        end
        end

        % Value changed function: AngleBox
        function AngleBoxValueChanged(app, event)
            angle = app.AngleBox.Value;
            app.AngleBox.Value = angle;
            title(app.Axes,sprintf('Ray Angle: %.1f°', angle));
            
        end

        % Value changed function: AngleSlider
        function InputAngleValueChanged(app, event)
            angle = app.AngleSlider.Value;
            app.AngleBox.Value = angle; % Sync slider and box
            title(app.Axes, sprintf('Ray Angle: %.1f°', angle));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes = uiaxes(app.UIFigure);
            app.Axes.XLim = [0 1];
            app.Axes.YLim = [0 1];
            app.Axes.ZLim = [0 1];
            app.Axes.CLim = [0 1];
            app.Axes.ALim = [0 1];
            app.Axes.XTick = [];
            app.Axes.YTick = [];
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Diffraction/Dispersion Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.OpticalElementsInterfacesDropDownLabel = uilabel(app.UIFigure);
            app.OpticalElementsInterfacesDropDownLabel.Text = 'Optical Elements/Interfaces';
            app.ElementsMenu = uidropdown(app.UIFigure);
            app.ElementsMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.ElementsMenu.ValueChangedFcn = createCallbackFcn(app, @ElementsMenuValueChanged, true);
            app.ElementsMenu.Value = 'Air/Glass Surface';
            app.StartingRayAngleSliderLabel = uilabel(app.UIFigure);
            app.StartingRayAngleSliderLabel.Text = 'Starting Ray Angle';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @InputAngleValueChanged, true);
            app.OpticalGlassDropDownLabel = uilabel(app.UIFigure);
            app.OpticalGlassDropDownLabel.Text = 'Optical Glass';
            app.GlassMenu = uidropdown(app.UIFigure);
            app.GlassMenu.Items = {'Air/Glass Surface', 'Glass/Air Surface', 'Plane Parallel Plate', '60-Degree Prism', 'Simple Positive Lens'};
            app.GlassMenu.Value = 'Air/Glass Surface';
            app.AngleBox = uieditfield(app.UIFigure, 'numeric');
            app.AngleBox.ValueChangedFcn = createCallbackFcn(app, @AngleBoxValueChanged, true);
            app.AngleBox.Value = 30;
            app.IncidentLightSourceLabel = uilabel(app.UIFigure);
            app.IncidentLightSourceLabel.Text = 'Incident Light Source';
            app.SourceMenu = uidropdown(app.UIFigure);
            app.SourceMenu.Items = {'white light', 'red chromatic light', 'yellow chromatic light', 'green chromatic light', 'blue chromatic light'};
            app.SourceMenu.ValueChangedFcn = createCallbackFcn(app, @SourceMenuValueChanged, true);
            app.SourceMenu.Value = 'white light';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffractionDispersionSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end